classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChooseCertificateButton    matlab.ui.control.Button
        ChooseDataButton           matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        DataTextAreaLabel          matlab.ui.control.Label
        DataTextArea               matlab.ui.control.TextArea
        CreateButton               matlab.ui.control.Button
        XAxisEditFieldLabel        matlab.ui.control.Label
        XAxisEditField             matlab.ui.control.NumericEditField
        YAxisEditFieldLabel        matlab.ui.control.Label
        YAxisEditField             matlab.ui.control.NumericEditField
        PositionLabel              matlab.ui.control.Label
        CERTIFICATEGENERATORLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        text_names cell
        len 
        imgData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseCertificateButton
        function ChooseCertificateButtonPushed(app, event)
          [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif', 'Image Files (*.jpg, *.png, *.jpeg, *.tif)'}, 'Select an Image File');
        
        if isequal(file, 0)
            disp('User canceled file selection');
        else
            imagePath = fullfile(path, file);
            
            if exist(imagePath, 'file')
                app.imgData = imread(imagePath);
                app.Image.ImageSource = app.imgData;
            else
                disp('File does not exist or is not an image');
            end
        end
        end

        % Button pushed function: ChooseDataButton
        function ChooseDataButtonPushed(app, event)
             [file, path] = uigetfile({'*.csv; *.xlsx; *.xls', 'CSV Files (*.csv; *.xlsx; *.xls)'}, 'Select a CSV File');
             filepath = fullfile(path, file);
             [~,txt] = xlsread(filepath);

             app.len=length(txt);  
             app.text_names = cell(app.len, 1);

            for i = 1:app.len
                app.text_names{i} = char(txt{i, 2});
            end

            set(app.DataTextArea, 'Value', app.text_names);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            xValue = app.XAxisEditField.Value;
            yValue = app.YAxisEditField.Value;
            
            for i=2:app.len
                name = app.text_names{i}; 

                position = [xValue yValue];         
                
                RGB = insertText(app.imgData,position,name,'FontSize',50,'BoxOpacity',0);
              
                figure;
                imshow(RGB);        
                %Obtain and display figure in color
                
                y=i-1;
                folderPath = 'E:\Study\University\IT509 DSP\Matlab files\Final project\AUTOMATIC CERTIFICATE GENERATION USING MATLAB\Generated Certificates';
                filename = fullfile(folderPath, ['Certificate_Topic_' num2str(y) '.tif']);
                saveas(gcf, filename);
            end   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseCertificateButton = uibutton(app.UIFigure, 'push');
            app.ChooseCertificateButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCertificateButtonPushed, true);
            app.ChooseCertificateButton.Text = 'Choose Certificate';
            app.ChooseDataButton = uibutton(app.UIFigure, 'push');
            app.ChooseDataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataButtonPushed, true);
            app.ChooseDataButton.Text = 'Choose Data';
            app.Image = uiimage(app.UIFigure);
            app.DataTextAreaLabel = uilabel(app.UIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.UIFigure);
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.XAxisEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisEditFieldLabel.Text = 'X-Axis';
            app.XAxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.YAxisEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisEditFieldLabel.Text = 'Y-Axis';
            app.YAxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.CERTIFICATEGENERATORLabel = uilabel(app.UIFigure);
            app.CERTIFICATEGENERATORLabel.Text = 'CERTIFICATE GENERATOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end